classdef guiEval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClusterEvaluationUIFigure      matlab.ui.Figure
        OpenMATFileButton              matlab.ui.control.Button
        MATDIREditFieldLabel           matlab.ui.control.Label
        MATDIREditField                matlab.ui.control.EditField
        APTable                        matlab.ui.control.Table
        ClusterRowMACIncOrderCheckBox  matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        LogMessageTextAreaLabel        matlab.ui.control.Label
        LogMessageTextArea             matlab.ui.control.TextArea
        MACIncOrderCheckBox            matlab.ui.control.CheckBox
        DateDecOrderCheckBox           matlab.ui.control.CheckBox
        CSVDIREditFieldLabel           matlab.ui.control.Label
        CSVDIREditField                matlab.ui.control.EditField
        OpenCSVFileButton              matlab.ui.control.Button
        AlphaSpinnerLabel              matlab.ui.control.Label
        AlphaSpinner                   matlab.ui.control.Spinner
        BetaSpinnerLabel               matlab.ui.control.Label
        BetaSpinner                    matlab.ui.control.Spinner
        CVSLabelOptionListBoxLabel     matlab.ui.control.Label
        CVSLabelOptionListBox          matlab.ui.control.ListBox
        JointTable                     matlab.ui.control.Table
        OccurrenceEditFieldLabel       matlab.ui.control.Label
        OccurrenceEditField            matlab.ui.control.EditField
        DistributionTable              matlab.ui.control.Table
        IntersectThresholdDropDownLabel  matlab.ui.control.Label
        IntersectThresholdDropDown     matlab.ui.control.DropDown
        CheckBox                       matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        matFileDir = '';
        matFileReady = false(1);
        mat;
        
        csvFileDir = '';
        csvFileReady = false(1);
        csvData;
        
        pressedStart = false(1);
        
        apResult; % one of the AP result considering alpha and beta
        apGoodRecCorrespond;
        apGoodSet;
        recRepresentatives;
        beaconRecordSumMap;
        recordSet;
        
        TableApBasicColSize = 0;
        TableJointBasicColSize = 0;
        apClusterRow;
        distribution;
        
        colForm = '%d-%s (%d';
        colDelim = '-';
    end
    
    methods (Access = private)
        
        function evaluateRecord(app)
        end
        
        function [value, tf] = getIntersect(app, bitVecFromCluster, bitVecFromRecord, thres)
            numerator = bitVecFromCluster & bitVecFromRecord;
            numerator = sum(numerator);
            denom = sum(bitVecFromCluster | bitVecFromRecord);
            
            value = numerator / denom;
            tf = thres <= value;
        end
        
        function printMsg(app, text)
            app.LogMessageTextArea.Value = sprintf('%s\n', text);
            drawnow;
        end
        
        function [alpha, beta] = getAlphaBeta(app)
            alpha = app.AlphaSpinner.Value;
            beta = app.BetaSpinner.Value;
        end
        
        function [fixedDate] = fixDate(app, date)
            
            if strcmp(date(2),'-')
                date = ['0',date];
            end
            
            if length(date) == 4
                date = [date(1:3), '0', date(4:end)];
            end
            
            date(3) = '/';
            
            fixedDate = [date, '/18'];
            
            % disp(fixedDate)
        end
        
        function [fixedTime] = fixTime(app, time)
            
            if length(time) == 4
                time = ['0',time];
            end
            
            fixedTime = [time, ':00'];
            
        end
        
        %this is for reading the csv file into a cell array
        function [CSVData] = ReadActivityCSV(app)
            csvF = fopen(app.csvFileDir,'r');
            
            CSVData = cell(0,5);
            
            line = fgetl(csvF); %first line is explaining the format
            line = fgetl(csvF);
            
            while (ischar(line))
                
                lineCell = cell(1,5);
                commas = strfind(line, ',');
                
                lineCell{1} = fixDate(app, line(1:commas(1)-1));
                lineCell{2} = fixTime(app, line(commas(1)+1:commas(2)-1));
                lineCell{3} = fixTime(app, line(commas(2)+1:commas(3)-1));
                lineCell{4} = line(commas(3)+1:commas(4)-1);
                lineCell{5} = line(commas(4)+1:end);
                
                CSVData(end+1,:) = lineCell;
                
                line = fgetl(csvF);
                
            end
        end
        
        function setRecordMap(app)
            app.recRepresentatives = app.apResult{1, 2};
            recordRepresentatives = app.recRepresentatives;
            [sizeRow, sizeCol] = size(recordRepresentatives);
            keySet = 1:sizeCol;
            valueSet = zeros(1, sizeCol);
            recMap = containers.Map(keySet,valueSet);
            
            for rowIndex = 1:sizeRow
                row = recordRepresentatives(rowIndex, :);
                % Find the nonzero elements
                idx=find(row);
                
                for jj = idx
                    recMap(jj) = recMap(jj) + 1;
                end
            end
            app.beaconRecordSumMap = recMap;
        end
        
        function setDistributionData(app)
            recordRepresentatives = app.recRepresentatives;
            [sizeRow, ~] = size(recordRepresentatives);
            
            recSet = app.recordSet;
            [~, sizeRec] = size(recSet);
            dist = zeros(sizeRow, sizeRec);
            
            printMsg(app, 'Checking intersection distribution');
            for rr = 1:sizeRow
                bitVecFromRecord = recordRepresentatives(rr, :);
                
                for rec = 1:sizeRec
                    bitVecFromCluster = recSet{3, rec};
                    [value, ~] = getIntersect(app, bitVecFromCluster, bitVecFromRecord, 0);
                    dist(rr, rec) = value;
                end
            end
            
            app.distribution = dist;
        end
        
        function setApResult(app)
            [alpha, beta] = getAlphaBeta(app);
            app.apResult = app.mat.apResults{alpha, beta};
        end
        
        function setGoodRecCorrespond(app)
            app.apGoodRecCorrespond = app.apResult{1, 1};
        end
        
        function apUpdate(app)
            if app.matFileReady
                setApResult(app);
                setGoodRecCorrespond(app);
                setRecordMap(app);
                
                app.recordSet = app.mat.recordSet;
                setDistributionData(app);
            end
            
            % if app.matFileReady && app.csvFileReady
            % end
        end
        
        function [sortedMACs, occurrence] = getMACSort(app, orderIncrementing)
            recMap = app.beaconRecordSumMap;
            
            vv = cell2mat(values(recMap));
            vec = unique(vv); % remove duplicated values
            occurrence = [];
            if orderIncrementing
                occurrence = sort(vec);
            else
                occurrence = sort(vec,'descend');
            end
            
            % get keySet which are MAC indices as String type
            keySet = cell2mat(keys(recMap));
            sortedMACs = {};
            for v = occurrence
                for k = keySet
                    if recMap(k) == v
                        addrs = app.mat.macSetClean{k, 1};
                        count = length(addrs);
                        macAddr = string(addrs{1,1});
                        msg = sprintf(app.colForm, k, macAddr, v);
                        if count > 1
                            msg = sprintf('%s, with %d random MACs)', msg, count - 1);
                        else
                            msg = sprintf('%s)', msg);
                        end
                        sortedMACs{end + 1} = msg;
                    end
                end
            end
            
        end
        
        function [appended, occurrence] = appendTableColumns(app, table, basicColLength)
            % orderDescending = app.MACIncOrderCheckBox.Value;
            orderIncrementing = app.MACIncOrderCheckBox.Value;
            [content, occurrence] = getMACSort(app, orderIncrementing);
            len = length(content);
            
            appended = table.ColumnName(1:basicColLength);
            
            for ii = 1:len
                c = char(content(ii));
                appended{end + 1} = c;
            end
        end
        
        function setApRow(app, table, tableName)
            printMsg(app, sprintf('Setting %s Rows', tableName));
            row = getAPClusterRow(app);
            table.RowName = row;
            printMsg(app, sprintf('Set %s Rows', tableName));
        end
        
        function setDistributionTable(app, table)
            [totalClusters, totalRecords] = size(app.distribution);
            data = zeros(totalClusters, totalRecords);
            keySet = 1:totalRecords;
            valueSet = zeros(1, totalRecords);
            DisMap = containers.Map(keySet,valueSet);
            orderIncrementing = app.CheckBox.Value;
            
            for cluster = keySet
                DisMap(cluster) = sum(app.recordSet{3, cluster});
            end
            
            vv = cell2mat(values(DisMap));
            vec = unique(vv); % remove duplicated values
            occurrence = [];
            if orderIncrementing
                occurrence = sort(vec);
            else
                occurrence = sort(vec,'descend');
            end
            
            col = {};
            for v = occurrence
                for k = keySet
                    if DisMap(k) == v
                        col{end + 1} = num2str(k);
                    end
                end
            end
            
            rows = cellfun(@str2double,table.RowName)';
            cc = cellfun(@str2double,col);
            data = {};
            for rr = rows
                dataRow = {};
                for ccc = cc
                    v = app.distribution(rr, ccc);
                    dataRow{end + 1} = v;
                end
                data(end + 1,:) = dataRow;
            end
            
            table.ColumnName = col;
            table.Data = data;
            
        end
        
        function setAPMACColumn(app, table, basicColLength, updateOccur)
            printMsg(app, 'Setting AP Table Columns');
            [appended, occurrence] = appendTableColumns(app, table, basicColLength);
            
            if updateOccur
                app.OccurrenceEditField.Value = sprintf('%d, ', occurrence);
            end
            
            table.ColumnName = appended;
            printMsg(app, 'Set AP Table Columns');
        end
        
        function [ret] = getAPClusterRow(app)
            ret = app.apClusterRow;
        end
        
        function setAPClusterRow(app)
            recordRepresentatives = app.recRepresentatives;
            [sizeRow, ~] = size(recordRepresentatives);
            keySet = 1:sizeRow;
            valueSet = zeros(1, sizeRow);
            clusterMap = containers.Map(keySet,valueSet);
            orderIncrementing = app.ClusterRowMACIncOrderCheckBox.Value;
            
            for cluster = keySet
                clusterMap(cluster) = sum(recordRepresentatives(cluster, :));
            end
            
            vv = cell2mat(values(clusterMap));
            vec = unique(vv); % remove duplicated values
            occurrence = [];
            if orderIncrementing
                occurrence = sort(vec);
            else
                occurrence = sort(vec,'descend');
            end
            
            row = {};
            for v = occurrence
                for k = keySet
                    if clusterMap(k) == v
                        row{end + 1} = num2str(k);
                    end
                end
            end
            
            % set(table,'RowName', row);
            % table.RowName = row;
            app.apClusterRow = row;
        end
        
        function setAPTableData(app, table)
            len = length(app.APTable.RowName);
            
            % fill time data and good indicies
            tableData = {};
            dataTime = {};
            dataGoodRecIdx = {};
            labelFromGoodRec = {};
            thres = app.IntersectThresholdDropDown.Value;
            for rr = 1:len
                rowIndex = str2num(app.APTable.RowName{rr}); % cluster number
                goodRecIndexes = app.apGoodRecCorrespond{rowIndex};
                timeStamps = '';
                locDataFromGoodRec = '';
                for ii = goodRecIndexes
                    logDate = app.apGoodSet{1, ii};
                    timeNum = app.apGoodSet{2, ii};
                    logTime = num2date(timeNum);
                    
                    [act, locGR] = getLabel(app.csvData, logDate, timeNum);
                    locDataFromGoodRec = sprintf('%s, %s (%d)', locDataFromGoodRec, locGR, ii);
                    tail = sprintf('%s-%s', logDate, logTime);
                    timeStamps = sprintf('%s, %s', timeStamps, tail);
                    
                end
                timeStamps = timeStamps(3:end);
                locDataFromGoodRec = locDataFromGoodRec(3:end);
                strIdx = sprintf('%d, ', goodRecIndexes);
                strIdx = strIdx(1:end - 2);
                
                % dataTime{end + 1} = timeStamps;
                % dataGoodRecIdx{end + 1} = strIdx;
                % labelFromGoodRec{end + 1} = locDataFromGoodRec;


                dataFormat = {timeStamps, strIdx, locDataFromGoodRec};
                tableData(end + 1,:) = dataFormat;
            end
            
            table.Data = tableData;
        end
        
        function apTableUpdate(app)
            setAPClusterRow(app);
            
            setApRow(app, app.APTable, 'AP Table');
            setAPMACColumn(app, app.APTable, app.TableApBasicColSize, true(1));
            
            setApRow(app, app.DistributionTable, 'AP Distribution Table');
            setDistributionTable(app, app.DistributionTable);
            
            setAPTableData(app, app.APTable);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TableApBasicColSize = length(app.APTable.ColumnName);
            app.TableJointBasicColSize = length(app.JointTable.ColumnName);
            % app.APTable.RowName = {'A'; 'B'; 'C'; 'D'};
            % app.APTable.Data = {1, 2;2, 3;3, 4;4, 5};
        end

        % Button pushed function: OpenMATFileButton
        function OpenMATFileButtonPushed(app, event)
            
            [file, path] = uigetfile('*.mat');
            tf = isequal(file,0);
            
            if ~tf % User did not select Cancel
                app.matFileDir = fullfile(path, file);
                app.MATDIREditField.Value = app.matFileDir;
                set(app.StartButton, 'Enable', 'off');
                printMsg(app, 'Loading Mat File');
                
                app.mat = load(app.matFileDir);
                
                set(app.StartButton, 'Enable', 'on');
                printMsg(app, 'Finished Loading Mat File');
                app.matFileReady = true(1);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.matFileReady
                uialert(app.ClusterEvaluationUIFigure,'Load or wait for loading MAT file','Try again', 'Icon','warning');
            elseif ~app.csvFileReady
                uialert(app.ClusterEvaluationUIFigure,'Load or wait for loading CSV file','Try again', 'Icon','warning');
            else
                app.pressedStart = true(1);
                app.apGoodSet = app.mat.goodRecordSet;
                
                apUpdate(app);
                apTableUpdate(app);
                % tableUpdate(app, app.JointTable, app.TableJointBasicColSize, false(1));
            end
            % disp(app.apResult);
            % [a, b] = getAlphaBeta(app);
        end

        % Button pushed function: OpenCSVFileButton
        function OpenCSVFileButtonPushed(app, event)
            [file, path] = uigetfile('*.csv');
            tf = isequal(file,0);
            if ~tf % User did not select Cancel
                app.csvFileDir = fullfile(path, file);
                app.CSVDIREditField.Value = app.csvFileDir;
                set(app.StartButton, 'Enable', 'off');
                printMsg(app, 'Loading CSV File');
                
                app.csvData = ReadActivityCSV(app);
                set(app.StartButton, 'Enable', 'on');
                printMsg(app, 'Finished Loading CSV File');
                app.csvFileReady = true(1);
            end
        end

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            % value = app.AlphaSpinner.Value;
            apUpdate(app);
            
        end

        % Value changed function: BetaSpinner
        function BetaSpinnerValueChanged(app, event)
            % value = app.BetaSpinner.Value;
            apUpdate(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClusterEvaluationUIFigure = uifigure;
            app.ClusterEvaluationUIFigure.Name = 'Cluster Evaluation';
            app.OpenMATFileButton = uibutton(app.ClusterEvaluationUIFigure, 'push');
            app.OpenMATFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMATFileButtonPushed, true);
            app.OpenMATFileButton.Text = 'Open MAT File';
            app.MATDIREditFieldLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.MATDIREditFieldLabel.Text = 'MAT DIR';
            app.MATDIREditField = uieditfield(app.ClusterEvaluationUIFigure, 'text');
            app.APTable = uitable(app.ClusterEvaluationUIFigure);
            app.APTable.ColumnName = {'Time(s) (Date-Time)'; 'Good Rec indices'; 'Label (From Good Rec)'; 'Label (From Intersect)'; 'Activity'};
            app.APTable.RowName = {};
            app.ClusterRowMACIncOrderCheckBox = uicheckbox(app.ClusterEvaluationUIFigure);
            app.ClusterRowMACIncOrderCheckBox.Text = 'Cluster (Row) MAC Inc Order';
            app.StartButton = uibutton(app.ClusterEvaluationUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LogMessageTextAreaLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.LogMessageTextAreaLabel.Text = 'Log Message';
            app.LogMessageTextArea = uitextarea(app.ClusterEvaluationUIFigure);
            app.MACIncOrderCheckBox = uicheckbox(app.ClusterEvaluationUIFigure);
            app.MACIncOrderCheckBox.Text = 'MAC Inc Order';
            app.DateDecOrderCheckBox = uicheckbox(app.ClusterEvaluationUIFigure);
            app.DateDecOrderCheckBox.Text = 'Date Dec Order';
            app.CSVDIREditFieldLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.CSVDIREditFieldLabel.Text = 'CSV DIR';
            app.CSVDIREditField = uieditfield(app.ClusterEvaluationUIFigure, 'text');
            app.OpenCSVFileButton = uibutton(app.ClusterEvaluationUIFigure, 'push');
            app.OpenCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCSVFileButtonPushed, true);
            app.OpenCSVFileButton.Text = 'Open CSV File';
            app.AlphaSpinnerLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.ClusterEvaluationUIFigure);
            app.AlphaSpinner.Limits = [1 Inf];
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.AlphaSpinner.Value = 1;
            app.BetaSpinnerLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.BetaSpinnerLabel.Text = 'Beta';
            app.BetaSpinner = uispinner(app.ClusterEvaluationUIFigure);
            app.BetaSpinner.Limits = [1 Inf];
            app.BetaSpinner.ValueChangedFcn = createCallbackFcn(app, @BetaSpinnerValueChanged, true);
            app.BetaSpinner.Value = 1;
            app.CVSLabelOptionListBoxLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.CVSLabelOptionListBoxLabel.Text = 'CVS Label Option';
            app.CVSLabelOptionListBox = uilistbox(app.ClusterEvaluationUIFigure);
            app.CVSLabelOptionListBox.Items = {'All'};
            app.CVSLabelOptionListBox.Multiselect = 'on';
            app.CVSLabelOptionListBox.Value = {'All'};
            app.JointTable = uitable(app.ClusterEvaluationUIFigure);
            app.JointTable.ColumnName = {'Date'; 'Start Time'; 'End Time'; 'CSV Label'; 'Merged'};
            app.JointTable.RowName = {};
            app.OccurrenceEditFieldLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.OccurrenceEditFieldLabel.Text = 'Occurrence';
            app.OccurrenceEditField = uieditfield(app.ClusterEvaluationUIFigure, 'text');
            app.DistributionTable = uitable(app.ClusterEvaluationUIFigure);
            app.DistributionTable.ColumnName = {'Col'};
            app.DistributionTable.RowName = {};
            app.IntersectThresholdDropDownLabel = uilabel(app.ClusterEvaluationUIFigure);
            app.IntersectThresholdDropDownLabel.Text = 'Intersect Threshold';
            app.IntersectThresholdDropDown = uidropdown(app.ClusterEvaluationUIFigure);
            app.IntersectThresholdDropDown.Items = {'0.1', '0.125', '0.15', '0.175', '0.2', '0.225', '0.25', '0.275', '0.3', '0.325', '0.35', '0.375', '0.4', '0.425', '0.45', '0.475', '0.5', '0.525', '0.55', '0.575', '0.6', '0.625', '0.65', '0.675', '0.7', '0.725', '0.75', '0.775', '0.8', '0.825'};
            app.IntersectThresholdDropDown.Value = '0.25';
            app.CheckBox = uicheckbox(app.ClusterEvaluationUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiEval

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClusterEvaluationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClusterEvaluationUIFigure)
        end
    end
end